classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GenelTab                     matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        DHTablosuPanel               matlab.ui.container.Panel
        EditField_JV_7               matlab.ui.control.NumericEditField
        EditField_JV_6               matlab.ui.control.NumericEditField
        EditField_JV_5               matlab.ui.control.NumericEditField
        EditField_JV_4               matlab.ui.control.NumericEditField
        EditField_JV_3               matlab.ui.control.NumericEditField
        EditField_JV_2               matlab.ui.control.NumericEditField
        EditField_JV_1               matlab.ui.control.NumericEditField
        EditField_JV_0               matlab.ui.control.NumericEditField
        ButtonGroup_7                matlab.ui.container.ButtonGroup
        Button_N_7                   matlab.ui.control.RadioButton
        Button_P_7                   matlab.ui.control.RadioButton
        Button_R_7                   matlab.ui.control.RadioButton
        ButtonGroup_6                matlab.ui.container.ButtonGroup
        Button_N_6                   matlab.ui.control.RadioButton
        Button_P_6                   matlab.ui.control.RadioButton
        Button_R_6                   matlab.ui.control.RadioButton
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        Button_N_5                   matlab.ui.control.RadioButton
        Button_P_5                   matlab.ui.control.RadioButton
        Button_R_5                   matlab.ui.control.RadioButton
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        Button_N_4                   matlab.ui.control.RadioButton
        Button_P_4                   matlab.ui.control.RadioButton
        Button_R_4                   matlab.ui.control.RadioButton
        ButtonGroup_3                matlab.ui.container.ButtonGroup
        Button_N_3                   matlab.ui.control.RadioButton
        Button_P_3                   matlab.ui.control.RadioButton
        Button_R_3                   matlab.ui.control.RadioButton
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        Button_N_2                   matlab.ui.control.RadioButton
        Button_P_2                   matlab.ui.control.RadioButton
        Button_R_2                   matlab.ui.control.RadioButton
        ButtonGroup_1                matlab.ui.container.ButtonGroup
        Button_N_1                   matlab.ui.control.RadioButton
        Button_P_1                   matlab.ui.control.RadioButton
        Button_R_1                   matlab.ui.control.RadioButton
        ButtonGroup_0                matlab.ui.container.ButtonGroup
        Button_N_0                   matlab.ui.control.RadioButton
        Button_P_0                   matlab.ui.control.RadioButton
        Button_R_0                   matlab.ui.control.RadioButton
        SliderVariable_7             matlab.ui.control.Slider
        SliderVariable_6             matlab.ui.control.Slider
        SliderVariable_5             matlab.ui.control.Slider
        SliderVariable_4             matlab.ui.control.Slider
        SliderVariable_3             matlab.ui.control.Slider
        SliderVariable_2             matlab.ui.control.Slider
        SliderVariable_1             matlab.ui.control.Slider
        JointVariableLabel           matlab.ui.control.Label
        SliderVariable_0             matlab.ui.control.Slider
        HesaplaButton                matlab.ui.control.Button
        EklemlerLabel                matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        EklemTipiLabel               matlab.ui.control.Label
        EditField_7alpha             matlab.ui.control.NumericEditField
        EditField_7a                 matlab.ui.control.NumericEditField
        EditField_7d                 matlab.ui.control.NumericEditField
        EditField_7theta             matlab.ui.control.NumericEditField
        EditField_6alpha             matlab.ui.control.NumericEditField
        EditField_6a                 matlab.ui.control.NumericEditField
        EditField_6d                 matlab.ui.control.NumericEditField
        EditField_6theta             matlab.ui.control.NumericEditField
        EditField_5alpha             matlab.ui.control.NumericEditField
        EditField_5a                 matlab.ui.control.NumericEditField
        EditField_5d                 matlab.ui.control.NumericEditField
        EditField_5theta             matlab.ui.control.NumericEditField
        EditField_4alpha             matlab.ui.control.NumericEditField
        EditField_4a                 matlab.ui.control.NumericEditField
        EditField_4d                 matlab.ui.control.NumericEditField
        EditField_4theta             matlab.ui.control.NumericEditField
        EditField_3alpha             matlab.ui.control.NumericEditField
        EditField_3a                 matlab.ui.control.NumericEditField
        EditField_3d                 matlab.ui.control.NumericEditField
        EditField_3theta             matlab.ui.control.NumericEditField
        EditField_2alpha             matlab.ui.control.NumericEditField
        EditField_2a                 matlab.ui.control.NumericEditField
        EditField_2d                 matlab.ui.control.NumericEditField
        EditField_2theta             matlab.ui.control.NumericEditField
        EditField_1alpha             matlab.ui.control.NumericEditField
        EditField_1a                 matlab.ui.control.NumericEditField
        EditField_1d                 matlab.ui.control.NumericEditField
        EditField_1theta             matlab.ui.control.NumericEditField
        EditField_0theta             matlab.ui.control.NumericEditField
        EditField_0d                 matlab.ui.control.NumericEditField
        EditField_0a                 matlab.ui.control.NumericEditField
        EditField_0alpha             matlab.ui.control.NumericEditField
        RobotumTab                   matlab.ui.container.Tab
        TransformasyonMatrisleriTab  matlab.ui.container.Tab
        T78Panel                     matlab.ui.container.Panel
        TM7_30                       matlab.ui.control.EditField
        TM7_31                       matlab.ui.control.EditField
        TM7_32                       matlab.ui.control.EditField
        TM7_33                       matlab.ui.control.EditField
        TM7_20                       matlab.ui.control.EditField
        TM7_21                       matlab.ui.control.EditField
        TM7_22                       matlab.ui.control.EditField
        TM7_23                       matlab.ui.control.EditField
        TM7_10                       matlab.ui.control.EditField
        TM7_11                       matlab.ui.control.EditField
        TM7_12                       matlab.ui.control.EditField
        TM7_13                       matlab.ui.control.EditField
        TM7_03                       matlab.ui.control.EditField
        TM7_02                       matlab.ui.control.EditField
        TM7_01                       matlab.ui.control.EditField
        TM7_00                       matlab.ui.control.EditField
        T67Panel                     matlab.ui.container.Panel
        TM6_30                       matlab.ui.control.EditField
        TM6_31                       matlab.ui.control.EditField
        TM6_32                       matlab.ui.control.EditField
        TM6_33                       matlab.ui.control.EditField
        TM6_20                       matlab.ui.control.EditField
        TM6_21                       matlab.ui.control.EditField
        TM6_22                       matlab.ui.control.EditField
        TM6_23                       matlab.ui.control.EditField
        TM6_10                       matlab.ui.control.EditField
        TM6_11                       matlab.ui.control.EditField
        TM6_12                       matlab.ui.control.EditField
        TM6_13                       matlab.ui.control.EditField
        TM6_03                       matlab.ui.control.EditField
        TM6_02                       matlab.ui.control.EditField
        TM6_01                       matlab.ui.control.EditField
        TM6_00                       matlab.ui.control.EditField
        T56Panel                     matlab.ui.container.Panel
        TM5_30                       matlab.ui.control.EditField
        TM5_31                       matlab.ui.control.EditField
        TM5_32                       matlab.ui.control.EditField
        TM5_33                       matlab.ui.control.EditField
        TM5_20                       matlab.ui.control.EditField
        TM5_21                       matlab.ui.control.EditField
        TM5_22                       matlab.ui.control.EditField
        TM5_23                       matlab.ui.control.EditField
        TM5_10                       matlab.ui.control.EditField
        TM5_11                       matlab.ui.control.EditField
        TM5_12                       matlab.ui.control.EditField
        TM5_13                       matlab.ui.control.EditField
        TM5_03                       matlab.ui.control.EditField
        TM5_02                       matlab.ui.control.EditField
        TM5_01                       matlab.ui.control.EditField
        TM5_00                       matlab.ui.control.EditField
        T45Panel                     matlab.ui.container.Panel
        TM4_30                       matlab.ui.control.EditField
        TM4_31                       matlab.ui.control.EditField
        TM4_32                       matlab.ui.control.EditField
        TM4_33                       matlab.ui.control.EditField
        TM4_20                       matlab.ui.control.EditField
        TM4_21                       matlab.ui.control.EditField
        TM4_22                       matlab.ui.control.EditField
        TM4_23                       matlab.ui.control.EditField
        TM4_10                       matlab.ui.control.EditField
        TM4_11                       matlab.ui.control.EditField
        TM4_12                       matlab.ui.control.EditField
        TM4_13                       matlab.ui.control.EditField
        TM4_03                       matlab.ui.control.EditField
        TM4_02                       matlab.ui.control.EditField
        TM4_01                       matlab.ui.control.EditField
        TM4_00                       matlab.ui.control.EditField
        T34Panel                     matlab.ui.container.Panel
        TM3_30                       matlab.ui.control.EditField
        TM3_31                       matlab.ui.control.EditField
        TM3_32                       matlab.ui.control.EditField
        TM3_33                       matlab.ui.control.EditField
        TM3_20                       matlab.ui.control.EditField
        TM3_21                       matlab.ui.control.EditField
        TM3_22                       matlab.ui.control.EditField
        TM3_23                       matlab.ui.control.EditField
        TM3_10                       matlab.ui.control.EditField
        TM3_11                       matlab.ui.control.EditField
        TM3_12                       matlab.ui.control.EditField
        TM3_13                       matlab.ui.control.EditField
        TM3_03                       matlab.ui.control.EditField
        TM3_02                       matlab.ui.control.EditField
        TM3_01                       matlab.ui.control.EditField
        TM3_00                       matlab.ui.control.EditField
        T23Panel                     matlab.ui.container.Panel
        TM2_30                       matlab.ui.control.EditField
        TM2_31                       matlab.ui.control.EditField
        TM2_32                       matlab.ui.control.EditField
        TM2_33                       matlab.ui.control.EditField
        TM2_20                       matlab.ui.control.EditField
        TM2_21                       matlab.ui.control.EditField
        TM2_22                       matlab.ui.control.EditField
        TM2_23                       matlab.ui.control.EditField
        TM2_10                       matlab.ui.control.EditField
        TM2_11                       matlab.ui.control.EditField
        TM2_12                       matlab.ui.control.EditField
        TM2_13                       matlab.ui.control.EditField
        TM2_03                       matlab.ui.control.EditField
        TM2_02                       matlab.ui.control.EditField
        TM2_01                       matlab.ui.control.EditField
        TM2_00                       matlab.ui.control.EditField
        T12Panel                     matlab.ui.container.Panel
        TM1_30                       matlab.ui.control.EditField
        TM1_31                       matlab.ui.control.EditField
        TM1_32                       matlab.ui.control.EditField
        TM1_33                       matlab.ui.control.EditField
        TM1_20                       matlab.ui.control.EditField
        TM1_21                       matlab.ui.control.EditField
        TM1_22                       matlab.ui.control.EditField
        TM1_23                       matlab.ui.control.EditField
        TM1_10                       matlab.ui.control.EditField
        TM1_11                       matlab.ui.control.EditField
        TM1_12                       matlab.ui.control.EditField
        TM1_13                       matlab.ui.control.EditField
        TM1_03                       matlab.ui.control.EditField
        TM1_02                       matlab.ui.control.EditField
        TM1_01                       matlab.ui.control.EditField
        TM1_00                       matlab.ui.control.EditField
        T01Panel                     matlab.ui.container.Panel
        TM0_30                       matlab.ui.control.EditField
        TM0_31                       matlab.ui.control.EditField
        TM0_32                       matlab.ui.control.EditField
        TM0_33                       matlab.ui.control.EditField
        TM0_20                       matlab.ui.control.EditField
        TM0_21                       matlab.ui.control.EditField
        TM0_22                       matlab.ui.control.EditField
        TM0_23                       matlab.ui.control.EditField
        TM0_10                       matlab.ui.control.EditField
        TM0_11                       matlab.ui.control.EditField
        TM0_12                       matlab.ui.control.EditField
        TM0_13                       matlab.ui.control.EditField
        TM0_03                       matlab.ui.control.EditField
        TM0_02                       matlab.ui.control.EditField
        TM0_01                       matlab.ui.control.EditField
        TM0_00                       matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function tf_to_tftab(app, matrices)
            for i = 0:7 % İsimlendirme 0'a göre
                for j = 0:3
                    for k = 0:3
                        index_box_name = sprintf('TM%d_%d%d', i, j, k);
                        assignin('base', 'matrices_index',matrices{i+1}(k+1, j+1));
                        app.(index_box_name).Value = sprintf('%.5g', matrices{i+1}(k+1,j+1));
                    end
                end
                
            end
            
        end
        
        function calculate_total_tf(app, matrices)
            total_transformation_matrix = 1;
            for i = 1:size(matrices)
                total_transformation_matrix = total_transformation_matrix * matrices{i};
            end
            assignin('base', 'total_matrix',total_transformation_matrix);

        end
        
        function draw_graphic(app, points)
                 % UI figure oluştur
                fig = app.UIFigure;
                fig.Name = 'Matlab UI Örneği';
                
                % Axes bileşeni oluştur
                
                ax  = app.UIAxes;
                
                % 3D çizim yap
                % plot3(ax, points(1, :), points(2, :), points(3, :), 'o-');
                  % Tüm noktaları birleştirmek için 'hold on' kullanılır
                plot3(ax, points(1, 1), points(2, 1), points(3, 1), 'o-');
                hold(ax, 'on');

                for i = 2:size(points, 2)
                    radio_button_name_R = sprintf('Button_R_%d', i);
                    radio_button_name_P = sprintf('Button_P_%d', i);
                    radio_button_name_N = sprintf('Button_N_%d', i);
                
                    if radio_button_name_R
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'ro-');
                    elseif radio_button_name_P
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'ro-');
                    elseif radio_button_name_N
                        plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'go-');
                    end
                    % Diğer noktaları çizgi ile birleştir
                    % plot3(ax, points(1, i-1:i), points(2, i-1:i), points(3, i-1:i), 'o-');
                    % hold on;
                end
                hold(ax, 'off'); % 'hold off' ile çizim modu kapatılır

                grid(app.UIAxes, 'on');
                xlabel(ax, 'X Ekseni');
                ylabel(ax, 'Y Ekseni');
                zlabel(ax, 'Z Ekseni');
                title(ax, 'Robot');

            end
        
            function points = calculate_joint_points(app, matrices, initial_point)
                assignin('base', 'initial_point', initial_point);
                points = initial_point;
                assignin('base', 'size_of_matrices', size(matrices, 2));
                for i = 1:size(matrices, 2)
                    %new_point = matrices{i} * points(:,end);
                    new_point = matrices{i} * initial_point;
                    assignin('base', 'new_point', new_point);
                    assignin('base', 'point_end', points(:,end));
                    points(:, end + 1) = new_point;
                end
                assignin('base', 'joint_point', points);
            end

        
            function results = calculate_tf_0_N(app, matrices)
                results = cell(1,size(matrices, 2));
                results{1} = matrices{1};
                assignin('base', 'denemee', results{end});
                for i = 2:(size(matrices, 2))
                    new_tf = results{i-1}*matrices{i};
                    assignin('base', 'new_tf', new_tf);
                    results{i} = new_tf;
                end
                assignin('base', 'results_matrices', results);
            end
        
            function results = jacobian_matrix_calculator(app, transformation_input)
                w0_0 = [0;0;0];
                v0_0 = [0;0;0];


                for i =1:8
                    rotation_matrix = rotationmatrixcalculator(transformation_input{i});
                    p_matrix = pmatrixcalculator(transformation_input{i});

                    radio_button_name_R = sprintf('Button_R_%d', i);
                    radio_button_name_P = sprintf('Button_P_%d', i);
                    radio_button_name_N = sprintf('Button_N_%d', i);

                    if radio_button_name_R
                        w_new =  
                    elseif radio_button_name_P

                    elseif radio_button_name_N

                    end
                end
            end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
            input_dh = [];
            for i = 0:7
                alpha_name = sprintf('EditField_%dalpha', i);
                a_name = sprintf('EditField_%da', i);
                d_name = sprintf('EditField_%dd', i);
                theta_name = sprintf('EditField_%dtheta', i);
                radio_button_name_R = sprintf('Button_R_%d', i);
                radio_button_name_P = sprintf('Button_P_%d', i);
                % radio_button_name_N = sprintf('Button_N_%d', i);
                slider_name = sprintf('SliderVariable_%d', i);
                alpha = app.(alpha_name).Value;
                a = app.(a_name).Value;
                d = app.(d_name).Value;
                theta = app.(theta_name).Value;
                if app.(radio_button_name_R).Value
                    theta = theta + app.(slider_name).Value;
                elseif app.(radio_button_name_P).Value
                    d = d + app.(slider_name).Value;
                end
                input_dh(end + 1, :) = [alpha,a,d,theta];
            end
            assignin('base', 'Input_dh', input_dh);
            matrices = transformationmatrixcalculator(input_dh);
            assignin('base', 'matrices', matrices);
            tf_to_tftab(app, matrices);
            calculate_total_tf(app, matrices);
            calculated_tf_0_N = calculate_tf_0_N(app, matrices);
            points_calculated = calculate_joint_points(app, calculated_tf_0_N, [0;0;0;1]);
            draw_graphic(app, points_calculated);
        end

        % Value changed function: SliderVariable_4
        function SliderVariable_4ValueChanged(app, event)
            value = app.SliderVariable_4.Value;
            app.EditField_JV_4.Value = value;
        end

        % Value changed function: EditField_JV_4
        function EditField_JV_4ValueChanged(app, event)
            value = app.EditField_JV_4.Value;
            app.SliderVariable_4.Value = value;
        end

        % Value changed function: SliderVariable_0
        function SliderVariable_0ValueChanged(app, event)
            value = app.SliderVariable_0.Value;
            app.EditField_JV_0.Value = value;
        end

        % Value changed function: SliderVariable_1
        function SliderVariable_1ValueChanged(app, event)
            value = app.SliderVariable_1.Value;
            app.EditField_JV_1.Value = value;
        end

        % Value changed function: SliderVariable_2
        function SliderVariable_2ValueChanged(app, event)
            value = app.SliderVariable_2.Value;
            app.EditField_JV_2.Value = value;
        end

        % Value changed function: SliderVariable_3
        function SliderVariable_3ValueChanged(app, event)
            value = app.SliderVariable_3.Value;
            app.EditField_JV_3.Value = value;
        end

        % Value changed function: SliderVariable_5
        function SliderVariable_5ValueChanged(app, event)
            value = app.SliderVariable_5.Value;
            app.EditField_JV_5.Value = value;
        end

        % Value changed function: SliderVariable_6
        function SliderVariable_6ValueChanged(app, event)
            value = app.SliderVariable_6.Value;
            app.EditField_JV_6.Value = value;
        end

        % Value changed function: SliderVariable_7
        function SliderVariable_7ValueChanged(app, event)
            value = app.SliderVariable_7.Value;
            app.EditField_JV_7.Value = value;
        end

        % Value changed function: EditField_JV_0
        function EditField_JV_0ValueChanged(app, event)
            value = app.EditField_JV_0.Value;
            app.SliderVariable_0.Value = value;
        end

        % Value changed function: EditField_JV_1
        function EditField_JV_1ValueChanged(app, event)
            value = app.EditField_JV_1.Value;
            app.SliderVariable_1.Value = value;
        end

        % Value changed function: EditField_JV_2
        function EditField_JV_2ValueChanged(app, event)
            value = app.EditField_JV_2.Value;
            app.SliderVariable_2.Value = value;
        end

        % Value changed function: EditField_JV_3
        function EditField_JV_3ValueChanged(app, event)
            value = app.EditField_JV_3.Value;
            app.SliderVariable_3.Value = value;
        end

        % Value changed function: EditField_JV_5
        function EditField_JV_5ValueChanged(app, event)
            value = app.EditField_JV_5.Value;
            app.SliderVariable_5.Value = value;
        end

        % Value changed function: EditField_JV_6
        function EditField_JV_6ValueChanged(app, event)
            value = app.EditField_JV_6.Value;
            app.SliderVariable_6.Value = value;
        end

        % Value changed function: EditField_JV_7
        function EditField_JV_7ValueChanged(app, event)
            value = app.EditField_JV_7.Value;
            app.SliderVariable_7.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenelTab = uitab(app.TabGroup);
            app.GenelTab.Title = 'Genel';
            app.DHTablosuPanel = uipanel(app.GenelTab);
            app.DHTablosuPanel.Title = 'DH Tablosu';
            app.EditField_0alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_0a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_0d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_0theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_1alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_2alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_3alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_4alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_5alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_6alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7theta = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7d = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7a = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_7alpha = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EklemTipiLabel = uilabel(app.DHTablosuPanel);
            app.EklemTipiLabel.Text = 'Eklem Tipi';
            app.Label = uilabel(app.DHTablosuPanel);
            app.Label.Text = 'α';
            app.aLabel = uilabel(app.DHTablosuPanel);
            app.aLabel.Text = 'a';
            app.dLabel = uilabel(app.DHTablosuPanel);
            app.dLabel.Text = 'd';
            app.Label_2 = uilabel(app.DHTablosuPanel);
            app.Label_2.Text = 'θ';
            app.Label_3 = uilabel(app.DHTablosuPanel);
            app.Label_3.Text = '0-1';
            app.Label_4 = uilabel(app.DHTablosuPanel);
            app.Label_4.Text = '1-2';
            app.Label_5 = uilabel(app.DHTablosuPanel);
            app.Label_5.Text = '2-3';
            app.Label_6 = uilabel(app.DHTablosuPanel);
            app.Label_6.Text = '3-4';
            app.Label_7 = uilabel(app.DHTablosuPanel);
            app.Label_7.Text = '4-5';
            app.Label_8 = uilabel(app.DHTablosuPanel);
            app.Label_8.Text = '5-6';
            app.Label_9 = uilabel(app.DHTablosuPanel);
            app.Label_9.Text = '6-7';
            app.Label_10 = uilabel(app.DHTablosuPanel);
            app.Label_10.Text = '7-8';
            app.EklemlerLabel = uilabel(app.DHTablosuPanel);
            app.EklemlerLabel.Text = 'Eklemler';
            app.HesaplaButton = uibutton(app.DHTablosuPanel, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.SliderVariable_0 = uislider(app.DHTablosuPanel);
            app.SliderVariable_0.Limits = [-360 360];
            app.SliderVariable_0.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_0ValueChanged, true);
            app.JointVariableLabel = uilabel(app.DHTablosuPanel);
            app.JointVariableLabel.Text = 'Eklem Değişkeni Değeri';
            app.SliderVariable_1 = uislider(app.DHTablosuPanel);
            app.SliderVariable_1.Limits = [-360 360];
            app.SliderVariable_1.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_1ValueChanged, true);
            app.SliderVariable_2 = uislider(app.DHTablosuPanel);
            app.SliderVariable_2.Limits = [-360 360];
            app.SliderVariable_2.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_2ValueChanged, true);
            app.SliderVariable_3 = uislider(app.DHTablosuPanel);
            app.SliderVariable_3.Limits = [-360 360];
            app.SliderVariable_3.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_3ValueChanged, true);
            app.SliderVariable_4 = uislider(app.DHTablosuPanel);
            app.SliderVariable_4.Limits = [-360 360];
            app.SliderVariable_4.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_4ValueChanged, true);
            app.SliderVariable_5 = uislider(app.DHTablosuPanel);
            app.SliderVariable_5.Limits = [-360 360];
            app.SliderVariable_5.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_5ValueChanged, true);
            app.SliderVariable_6 = uislider(app.DHTablosuPanel);
            app.SliderVariable_6.Limits = [-360 360];
            app.SliderVariable_6.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_6ValueChanged, true);
            app.SliderVariable_7 = uislider(app.DHTablosuPanel);
            app.SliderVariable_7.Limits = [-360 360];
            app.SliderVariable_7.ValueChangedFcn = createCallbackFcn(app, @SliderVariable_7ValueChanged, true);
            app.ButtonGroup_0 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_0 = uiradiobutton(app.ButtonGroup_0);
            app.Button_R_0.Text = 'R';
            app.Button_R_0.Value = true;
            app.Button_P_0 = uiradiobutton(app.ButtonGroup_0);
            app.Button_P_0.Text = 'P';
            app.Button_N_0 = uiradiobutton(app.ButtonGroup_0);
            app.Button_N_0.Text = 'N';
            app.ButtonGroup_1 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_1 = uiradiobutton(app.ButtonGroup_1);
            app.Button_R_1.Text = 'R';
            app.Button_R_1.Value = true;
            app.Button_P_1 = uiradiobutton(app.ButtonGroup_1);
            app.Button_P_1.Text = 'P';
            app.Button_N_1 = uiradiobutton(app.ButtonGroup_1);
            app.Button_N_1.Text = 'N';
            app.ButtonGroup_2 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_R_2.Text = 'R';
            app.Button_R_2.Value = true;
            app.Button_P_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_P_2.Text = 'P';
            app.Button_N_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_N_2.Text = 'N';
            app.ButtonGroup_3 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button_R_3.Text = 'R';
            app.Button_R_3.Value = true;
            app.Button_P_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button_P_3.Text = 'P';
            app.Button_N_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button_N_3.Text = 'N';
            app.ButtonGroup_4 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_4 = uiradiobutton(app.ButtonGroup_4);
            app.Button_R_4.Text = 'R';
            app.Button_R_4.Value = true;
            app.Button_P_4 = uiradiobutton(app.ButtonGroup_4);
            app.Button_P_4.Text = 'P';
            app.Button_N_4 = uiradiobutton(app.ButtonGroup_4);
            app.Button_N_4.Text = 'N';
            app.ButtonGroup_5 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_5 = uiradiobutton(app.ButtonGroup_5);
            app.Button_R_5.Text = 'R';
            app.Button_R_5.Value = true;
            app.Button_P_5 = uiradiobutton(app.ButtonGroup_5);
            app.Button_P_5.Text = 'P';
            app.Button_N_5 = uiradiobutton(app.ButtonGroup_5);
            app.Button_N_5.Text = 'N';
            app.ButtonGroup_6 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_6 = uiradiobutton(app.ButtonGroup_6);
            app.Button_R_6.Text = 'R';
            app.Button_R_6.Value = true;
            app.Button_P_6 = uiradiobutton(app.ButtonGroup_6);
            app.Button_P_6.Text = 'P';
            app.Button_N_6 = uiradiobutton(app.ButtonGroup_6);
            app.Button_N_6.Text = 'N';
            app.ButtonGroup_7 = uibuttongroup(app.DHTablosuPanel);
            app.Button_R_7 = uiradiobutton(app.ButtonGroup_7);
            app.Button_R_7.Text = 'R';
            app.Button_R_7.Value = true;
            app.Button_P_7 = uiradiobutton(app.ButtonGroup_7);
            app.Button_P_7.Text = 'P';
            app.Button_N_7 = uiradiobutton(app.ButtonGroup_7);
            app.Button_N_7.Text = 'N';
            app.EditField_JV_0 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_0.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_0ValueChanged, true);
            app.EditField_JV_1 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_1.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_1ValueChanged, true);
            app.EditField_JV_2 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_2.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_2ValueChanged, true);
            app.EditField_JV_3 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_3.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_3ValueChanged, true);
            app.EditField_JV_4 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_4.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_4ValueChanged, true);
            app.EditField_JV_5 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_5.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_5ValueChanged, true);
            app.EditField_JV_6 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_6.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_6ValueChanged, true);
            app.EditField_JV_7 = uieditfield(app.DHTablosuPanel, 'numeric');
            app.EditField_JV_7.ValueChangedFcn = createCallbackFcn(app, @EditField_JV_7ValueChanged, true);
            app.Panel = uipanel(app.GenelTab);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RobotumTab = uitab(app.TabGroup);
            app.RobotumTab.Title = 'Robotum';
            app.TransformasyonMatrisleriTab = uitab(app.TabGroup);
            app.TransformasyonMatrisleriTab.Title = 'Transformasyon Matrisleri';
            app.T01Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T01Panel.Title = 'T 0 - 1';
            app.TM0_00 = uieditfield(app.T01Panel, 'text');
            app.TM0_00.Editable = 'off';
            app.TM0_01 = uieditfield(app.T01Panel, 'text');
            app.TM0_01.Editable = 'off';
            app.TM0_02 = uieditfield(app.T01Panel, 'text');
            app.TM0_02.Editable = 'off';
            app.TM0_03 = uieditfield(app.T01Panel, 'text');
            app.TM0_03.Editable = 'off';
            app.TM0_13 = uieditfield(app.T01Panel, 'text');
            app.TM0_13.Editable = 'off';
            app.TM0_12 = uieditfield(app.T01Panel, 'text');
            app.TM0_12.Editable = 'off';
            app.TM0_11 = uieditfield(app.T01Panel, 'text');
            app.TM0_11.Editable = 'off';
            app.TM0_10 = uieditfield(app.T01Panel, 'text');
            app.TM0_10.Editable = 'off';
            app.TM0_23 = uieditfield(app.T01Panel, 'text');
            app.TM0_23.Editable = 'off';
            app.TM0_22 = uieditfield(app.T01Panel, 'text');
            app.TM0_22.Editable = 'off';
            app.TM0_21 = uieditfield(app.T01Panel, 'text');
            app.TM0_21.Editable = 'off';
            app.TM0_20 = uieditfield(app.T01Panel, 'text');
            app.TM0_20.Editable = 'off';
            app.TM0_33 = uieditfield(app.T01Panel, 'text');
            app.TM0_33.Editable = 'off';
            app.TM0_32 = uieditfield(app.T01Panel, 'text');
            app.TM0_32.Editable = 'off';
            app.TM0_31 = uieditfield(app.T01Panel, 'text');
            app.TM0_31.Editable = 'off';
            app.TM0_30 = uieditfield(app.T01Panel, 'text');
            app.TM0_30.Editable = 'off';
            app.T12Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T12Panel.Title = 'T 1-2';
            app.TM1_00 = uieditfield(app.T12Panel, 'text');
            app.TM1_00.Editable = 'off';
            app.TM1_01 = uieditfield(app.T12Panel, 'text');
            app.TM1_01.Editable = 'off';
            app.TM1_02 = uieditfield(app.T12Panel, 'text');
            app.TM1_02.Editable = 'off';
            app.TM1_03 = uieditfield(app.T12Panel, 'text');
            app.TM1_03.Editable = 'off';
            app.TM1_13 = uieditfield(app.T12Panel, 'text');
            app.TM1_13.Editable = 'off';
            app.TM1_12 = uieditfield(app.T12Panel, 'text');
            app.TM1_12.Editable = 'off';
            app.TM1_11 = uieditfield(app.T12Panel, 'text');
            app.TM1_11.Editable = 'off';
            app.TM1_10 = uieditfield(app.T12Panel, 'text');
            app.TM1_10.Editable = 'off';
            app.TM1_23 = uieditfield(app.T12Panel, 'text');
            app.TM1_23.Editable = 'off';
            app.TM1_22 = uieditfield(app.T12Panel, 'text');
            app.TM1_22.Editable = 'off';
            app.TM1_21 = uieditfield(app.T12Panel, 'text');
            app.TM1_21.Editable = 'off';
            app.TM1_20 = uieditfield(app.T12Panel, 'text');
            app.TM1_20.Editable = 'off';
            app.TM1_33 = uieditfield(app.T12Panel, 'text');
            app.TM1_33.Editable = 'off';
            app.TM1_32 = uieditfield(app.T12Panel, 'text');
            app.TM1_32.Editable = 'off';
            app.TM1_31 = uieditfield(app.T12Panel, 'text');
            app.TM1_31.Editable = 'off';
            app.TM1_30 = uieditfield(app.T12Panel, 'text');
            app.TM1_30.Editable = 'off';
            app.T23Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T23Panel.Title = 'T 2-3';
            app.TM2_00 = uieditfield(app.T23Panel, 'text');
            app.TM2_00.Editable = 'off';
            app.TM2_01 = uieditfield(app.T23Panel, 'text');
            app.TM2_01.Editable = 'off';
            app.TM2_02 = uieditfield(app.T23Panel, 'text');
            app.TM2_02.Editable = 'off';
            app.TM2_03 = uieditfield(app.T23Panel, 'text');
            app.TM2_03.Editable = 'off';
            app.TM2_13 = uieditfield(app.T23Panel, 'text');
            app.TM2_13.Editable = 'off';
            app.TM2_12 = uieditfield(app.T23Panel, 'text');
            app.TM2_12.Editable = 'off';
            app.TM2_11 = uieditfield(app.T23Panel, 'text');
            app.TM2_11.Editable = 'off';
            app.TM2_10 = uieditfield(app.T23Panel, 'text');
            app.TM2_10.Editable = 'off';
            app.TM2_23 = uieditfield(app.T23Panel, 'text');
            app.TM2_23.Editable = 'off';
            app.TM2_22 = uieditfield(app.T23Panel, 'text');
            app.TM2_22.Editable = 'off';
            app.TM2_21 = uieditfield(app.T23Panel, 'text');
            app.TM2_21.Editable = 'off';
            app.TM2_20 = uieditfield(app.T23Panel, 'text');
            app.TM2_20.Editable = 'off';
            app.TM2_33 = uieditfield(app.T23Panel, 'text');
            app.TM2_33.Editable = 'off';
            app.TM2_32 = uieditfield(app.T23Panel, 'text');
            app.TM2_32.Editable = 'off';
            app.TM2_31 = uieditfield(app.T23Panel, 'text');
            app.TM2_31.Editable = 'off';
            app.TM2_30 = uieditfield(app.T23Panel, 'text');
            app.TM2_30.Editable = 'off';
            app.T34Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T34Panel.Title = 'T 3-4';
            app.TM3_00 = uieditfield(app.T34Panel, 'text');
            app.TM3_00.Editable = 'off';
            app.TM3_01 = uieditfield(app.T34Panel, 'text');
            app.TM3_01.Editable = 'off';
            app.TM3_02 = uieditfield(app.T34Panel, 'text');
            app.TM3_02.Editable = 'off';
            app.TM3_03 = uieditfield(app.T34Panel, 'text');
            app.TM3_03.Editable = 'off';
            app.TM3_13 = uieditfield(app.T34Panel, 'text');
            app.TM3_13.Editable = 'off';
            app.TM3_12 = uieditfield(app.T34Panel, 'text');
            app.TM3_12.Editable = 'off';
            app.TM3_11 = uieditfield(app.T34Panel, 'text');
            app.TM3_11.Editable = 'off';
            app.TM3_10 = uieditfield(app.T34Panel, 'text');
            app.TM3_10.Editable = 'off';
            app.TM3_23 = uieditfield(app.T34Panel, 'text');
            app.TM3_23.Editable = 'off';
            app.TM3_22 = uieditfield(app.T34Panel, 'text');
            app.TM3_22.Editable = 'off';
            app.TM3_21 = uieditfield(app.T34Panel, 'text');
            app.TM3_21.Editable = 'off';
            app.TM3_20 = uieditfield(app.T34Panel, 'text');
            app.TM3_20.Editable = 'off';
            app.TM3_33 = uieditfield(app.T34Panel, 'text');
            app.TM3_33.Editable = 'off';
            app.TM3_32 = uieditfield(app.T34Panel, 'text');
            app.TM3_32.Editable = 'off';
            app.TM3_31 = uieditfield(app.T34Panel, 'text');
            app.TM3_31.Editable = 'off';
            app.TM3_30 = uieditfield(app.T34Panel, 'text');
            app.TM3_30.Editable = 'off';
            app.T45Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T45Panel.Title = 'T 4-5';
            app.TM4_00 = uieditfield(app.T45Panel, 'text');
            app.TM4_00.Editable = 'off';
            app.TM4_01 = uieditfield(app.T45Panel, 'text');
            app.TM4_01.Editable = 'off';
            app.TM4_02 = uieditfield(app.T45Panel, 'text');
            app.TM4_02.Editable = 'off';
            app.TM4_03 = uieditfield(app.T45Panel, 'text');
            app.TM4_03.Editable = 'off';
            app.TM4_13 = uieditfield(app.T45Panel, 'text');
            app.TM4_13.Editable = 'off';
            app.TM4_12 = uieditfield(app.T45Panel, 'text');
            app.TM4_12.Editable = 'off';
            app.TM4_11 = uieditfield(app.T45Panel, 'text');
            app.TM4_11.Editable = 'off';
            app.TM4_10 = uieditfield(app.T45Panel, 'text');
            app.TM4_10.Editable = 'off';
            app.TM4_23 = uieditfield(app.T45Panel, 'text');
            app.TM4_23.Editable = 'off';
            app.TM4_22 = uieditfield(app.T45Panel, 'text');
            app.TM4_22.Editable = 'off';
            app.TM4_21 = uieditfield(app.T45Panel, 'text');
            app.TM4_21.Editable = 'off';
            app.TM4_20 = uieditfield(app.T45Panel, 'text');
            app.TM4_20.Editable = 'off';
            app.TM4_33 = uieditfield(app.T45Panel, 'text');
            app.TM4_33.Editable = 'off';
            app.TM4_32 = uieditfield(app.T45Panel, 'text');
            app.TM4_32.Editable = 'off';
            app.TM4_31 = uieditfield(app.T45Panel, 'text');
            app.TM4_31.Editable = 'off';
            app.TM4_30 = uieditfield(app.T45Panel, 'text');
            app.TM4_30.Editable = 'off';
            app.T56Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T56Panel.Title = 'T 5-6';
            app.TM5_00 = uieditfield(app.T56Panel, 'text');
            app.TM5_00.Editable = 'off';
            app.TM5_01 = uieditfield(app.T56Panel, 'text');
            app.TM5_01.Editable = 'off';
            app.TM5_02 = uieditfield(app.T56Panel, 'text');
            app.TM5_02.Editable = 'off';
            app.TM5_03 = uieditfield(app.T56Panel, 'text');
            app.TM5_03.Editable = 'off';
            app.TM5_13 = uieditfield(app.T56Panel, 'text');
            app.TM5_13.Editable = 'off';
            app.TM5_12 = uieditfield(app.T56Panel, 'text');
            app.TM5_12.Editable = 'off';
            app.TM5_11 = uieditfield(app.T56Panel, 'text');
            app.TM5_11.Editable = 'off';
            app.TM5_10 = uieditfield(app.T56Panel, 'text');
            app.TM5_10.Editable = 'off';
            app.TM5_23 = uieditfield(app.T56Panel, 'text');
            app.TM5_23.Editable = 'off';
            app.TM5_22 = uieditfield(app.T56Panel, 'text');
            app.TM5_22.Editable = 'off';
            app.TM5_21 = uieditfield(app.T56Panel, 'text');
            app.TM5_21.Editable = 'off';
            app.TM5_20 = uieditfield(app.T56Panel, 'text');
            app.TM5_20.Editable = 'off';
            app.TM5_33 = uieditfield(app.T56Panel, 'text');
            app.TM5_33.Editable = 'off';
            app.TM5_32 = uieditfield(app.T56Panel, 'text');
            app.TM5_32.Editable = 'off';
            app.TM5_31 = uieditfield(app.T56Panel, 'text');
            app.TM5_31.Editable = 'off';
            app.TM5_30 = uieditfield(app.T56Panel, 'text');
            app.TM5_30.Editable = 'off';
            app.T67Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T67Panel.Title = 'T 6-7';
            app.TM6_00 = uieditfield(app.T67Panel, 'text');
            app.TM6_00.Editable = 'off';
            app.TM6_01 = uieditfield(app.T67Panel, 'text');
            app.TM6_01.Editable = 'off';
            app.TM6_02 = uieditfield(app.T67Panel, 'text');
            app.TM6_02.Editable = 'off';
            app.TM6_03 = uieditfield(app.T67Panel, 'text');
            app.TM6_03.Editable = 'off';
            app.TM6_13 = uieditfield(app.T67Panel, 'text');
            app.TM6_13.Editable = 'off';
            app.TM6_12 = uieditfield(app.T67Panel, 'text');
            app.TM6_12.Editable = 'off';
            app.TM6_11 = uieditfield(app.T67Panel, 'text');
            app.TM6_11.Editable = 'off';
            app.TM6_10 = uieditfield(app.T67Panel, 'text');
            app.TM6_10.Editable = 'off';
            app.TM6_23 = uieditfield(app.T67Panel, 'text');
            app.TM6_23.Editable = 'off';
            app.TM6_22 = uieditfield(app.T67Panel, 'text');
            app.TM6_22.Editable = 'off';
            app.TM6_21 = uieditfield(app.T67Panel, 'text');
            app.TM6_21.Editable = 'off';
            app.TM6_20 = uieditfield(app.T67Panel, 'text');
            app.TM6_20.Editable = 'off';
            app.TM6_33 = uieditfield(app.T67Panel, 'text');
            app.TM6_33.Editable = 'off';
            app.TM6_32 = uieditfield(app.T67Panel, 'text');
            app.TM6_32.Editable = 'off';
            app.TM6_31 = uieditfield(app.T67Panel, 'text');
            app.TM6_31.Editable = 'off';
            app.TM6_30 = uieditfield(app.T67Panel, 'text');
            app.TM6_30.Editable = 'off';
            app.T78Panel = uipanel(app.TransformasyonMatrisleriTab);
            app.T78Panel.Title = 'T 7-8';
            app.TM7_00 = uieditfield(app.T78Panel, 'text');
            app.TM7_00.Editable = 'off';
            app.TM7_01 = uieditfield(app.T78Panel, 'text');
            app.TM7_01.Editable = 'off';
            app.TM7_02 = uieditfield(app.T78Panel, 'text');
            app.TM7_02.Editable = 'off';
            app.TM7_03 = uieditfield(app.T78Panel, 'text');
            app.TM7_03.Editable = 'off';
            app.TM7_13 = uieditfield(app.T78Panel, 'text');
            app.TM7_13.Editable = 'off';
            app.TM7_12 = uieditfield(app.T78Panel, 'text');
            app.TM7_12.Editable = 'off';
            app.TM7_11 = uieditfield(app.T78Panel, 'text');
            app.TM7_11.Editable = 'off';
            app.TM7_10 = uieditfield(app.T78Panel, 'text');
            app.TM7_10.Editable = 'off';
            app.TM7_23 = uieditfield(app.T78Panel, 'text');
            app.TM7_23.Editable = 'off';
            app.TM7_22 = uieditfield(app.T78Panel, 'text');
            app.TM7_22.Editable = 'off';
            app.TM7_21 = uieditfield(app.T78Panel, 'text');
            app.TM7_21.Editable = 'off';
            app.TM7_20 = uieditfield(app.T78Panel, 'text');
            app.TM7_20.Editable = 'off';
            app.TM7_33 = uieditfield(app.T78Panel, 'text');
            app.TM7_33.Editable = 'off';
            app.TM7_32 = uieditfield(app.T78Panel, 'text');
            app.TM7_32.Editable = 'off';
            app.TM7_31 = uieditfield(app.T78Panel, 'text');
            app.TM7_31.Editable = 'off';
            app.TM7_30 = uieditfield(app.T78Panel, 'text');
            app.TM7_30.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end